classdef Proejct2Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        LogInTab                matlab.ui.container.Tab
        UpdateCredential        matlab.ui.control.Button
        PASSWORDLabel           matlab.ui.control.Label
        USERNAMELabel           matlab.ui.control.Label
        PasswordField           matlab.ui.control.EditField
        UsernameField           matlab.ui.control.EditField
        StatusLabel             matlab.ui.control.Label
        LogInButton             matlab.ui.control.Button
        AUTENTICATIONLabel      matlab.ui.control.Label
        LDRsTab                 matlab.ui.container.Tab
        LDRsPanel               matlab.ui.container.Panel
        StopLDR                 matlab.ui.control.Button
        StatusLabel2            matlab.ui.control.Label
        PlotDropdown            matlab.ui.control.DropDown
        SAVELabel               matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
        PolarCheckbox           matlab.ui.control.CheckBox
        SerialConnectionButton  matlab.ui.control.Button
        ADQUISITIONLabel        matlab.ui.control.Label
        LuxRef                  matlab.ui.control.UIAxes
        SouthLDR                matlab.ui.control.UIAxes
        EastLDR                 matlab.ui.control.UIAxes
        WestLDR                 matlab.ui.control.UIAxes
        PolarLDR                matlab.ui.control.UIAxes
        NorthLDR                matlab.ui.control.UIAxes
        GraphTab_2              matlab.ui.container.Tab
        DataPanel               matlab.ui.container.Panel
        StopTab3                matlab.ui.control.Button
        SensorLabel             matlab.ui.control.Label
        StartButton_2           matlab.ui.control.Button
        WhiteNorthLamp          matlab.ui.control.Lamp
        WhiteEastLamp           matlab.ui.control.Lamp
        BlueEastLamp            matlab.ui.control.Lamp
        GreenCenterLamp         matlab.ui.control.Lamp
        BlueWestLamp            matlab.ui.control.Lamp
        WhiteWestLamp           matlab.ui.control.Lamp
        WhiteSouthLamp          matlab.ui.control.Lamp
        South2                  matlab.ui.control.UIAxes
        East2                   matlab.ui.control.UIAxes
        North2                  matlab.ui.control.UIAxes
        West2                   matlab.ui.control.UIAxes
        SouthLDR_2              matlab.ui.control.UIAxes
        EastLDR_2               matlab.ui.control.UIAxes
        WestLDR_2               matlab.ui.control.UIAxes
        PolarLDR_2              matlab.ui.control.UIAxes
        NorthLDR_2              matlab.ui.control.UIAxes
        AnalysisTab             matlab.ui.container.Tab
        AnalysisPanel           matlab.ui.container.Panel
        Label_3                 matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        MeanCheckbox            matlab.ui.control.CheckBox
        MaxCheckbox             matlab.ui.control.CheckBox
        MinCheckbox             matlab.ui.control.CheckBox
        LoadPlotButton          matlab.ui.control.Button
        VerticalSlider          matlab.ui.control.Slider
        Slider_2Label           matlab.ui.control.Label
        HorizontalSlider        matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        AnalysisPlot            matlab.ui.control.UIAxes
    end

      properties (Access = private)
        ValidUsername='Susan Laime';
        ValidPassword='85654'
        serialObj % Serial port object
        EnablePlotUpdates = true; 
        EnablePlotUpdate2=true;
        polar=false;
      end      
    
    properties (Access = public)
        ax_LDRPlot % Axes for plotting LDR data
        %btn_StartStop % Start/Stop Button
    end
    
    properties (Access = private)
        % Variables to store data for each LDR
        northData = [];
        eastData = [];
        southData = [];
        westData = [];
        luxRefData = [];

        LuxData        % To store the Lux data after loading
        LuxMin         % To store minimum Lux value
        LuxMax         % To store maximum Lux value
        LuxMean        % To store mean Lux value
        data
        Data 
        timer 
        s
        StopFlag = false; 
        PlotTimer;
    end
    
    methods (Access = public)
        
         function updatePlots(app)
            if ~app.EnablePlotUpdates
                return; % Exit the function early
            end
            % Update North LDR plot
            plot(app.NorthLDR, app.northData, 'g');
            xlabel(app.NorthLDR, 'Time (s)');
            ylabel(app.NorthLDR, 'North LDR');
            title(app.NorthLDR, 'North LDR Intensity Over Time');
        
            % Update East LDR plot
            plot(app.EastLDR, app.eastData, 'b');
            xlabel(app.EastLDR, 'Time (s)');
            ylabel(app.EastLDR, 'East LDR');
            title(app.EastLDR, 'East LDR Intensity Over Time');
        
            % Update South LDR plot
            plot(app.SouthLDR, app.southData, 'r');
            xlabel(app.SouthLDR, 'Time (s)');
            ylabel(app.SouthLDR, 'South LDR');
            title(app.SouthLDR, 'South LDR Intensity Over Time');
        
            % Update West LDR plot
            plot(app.WestLDR, app.westData, 'm');
            xlabel(app.WestLDR, 'Time (s)');
            ylabel(app.WestLDR, 'West LDR');
            title(app.WestLDR, 'West LDR Intensity Over Time');
        
            % Update LUX REF plot
            plot(app.PolarLDR, app.luxRefData, 'k');
            xlabel(app.PolarLDR, 'Time (s)');
            ylabel(app.PolarLDR, 'LUX REF');
            title(app.PolarLDR, 'Light Reference Over Time');
         end

        function updatePlots2(app)
            if ~app.EnablePlotUpdate2
                return; % Exit the function early
            end
            % Update North LDR plot
            plot(app.North2, app.northData, 'g');
            xlabel(app.North2, 'Time (s)');
            ylabel(app.North2, 'North LDR');
            title(app.North2, 'North LDR Intensity Over Time');
        
            % Update East LDR plot
            plot(app.East2, app.eastData, 'b');
            xlabel(app.East2, 'Time (s)');
            ylabel(app.East2, 'East LDR');
            title(app.East2, 'East LDR Intensity Over Time');
        
            % Update South LDR plot
            plot(app.South2, app.southData, 'r');
            xlabel(app.South2, 'Time (s)');
            ylabel(app.South2, 'South LDR');
            title(app.South2, 'South LDR Intensity Over Time');
        
            % Update West LDR plot
            plot(app.West2, app.westData, 'm');
            xlabel(app.West2, 'Time (s)');
            ylabel(app.West2, 'West LDR');
            title(app.West2, 'West LDR Intensity Over Time');

             if ~isempty(app.northData) && ~isempty(app.eastData) && ...
                   ~isempty(app.southData) && ~isempty(app.westData)
            
                latestNorth = app.northData(end);
                latestEast = app.eastData(end);
                latestSouth = app.southData(end);
                latestWest = app.westData(end);
            
                % Get the LDR values and their respective indices
                ldrValues = [latestNorth, latestEast, latestSouth, latestWest];
                [sortedValues, sortedIndices] = sort(ldrValues, 'descend');
            
                % Reset all lamps to 'off' or default color
                app.WhiteNorthLamp.Color = [0.5 0.5 0.5]; % Dimmed (gray)
                app.BlueEastLamp.Color = [0 0 0.8];       % Dimmed (gray)
                app.WhiteSouthLamp.Color = [0.5 0.5 0.5]; % Dimmed (gray)
                app.BlueWestLamp.Color = [0 0 0.8];       % Dimmed (gray)
            
                % Check the top two LDR values and ensure non-opposite directions
                if sortedIndices(1) == 1 || sortedIndices(1) == 3 % North or South
                    app.WhiteNorthLamp.Color = 'white';  % Turn on North Lamp
                    if sortedIndices(2) == 2 || sortedIndices(2) == 4 % East or West
                        if sortedIndices(2) == 2
                            %app.BlueEastLamp.Color = [0.529, 0.808, 0.922]; % Turn on East Lamp
                            app.WhiteEastLamp.Color='white';
                        else
                            %app.BlueWestLamp.Color = [0.529, 0.808, 0.922]; % Turn on West Lamp
                            app.WhiteWestLamp.Color='white';
                        end
                    end
                elseif sortedIndices(1) == 2 || sortedIndices(1) == 4 % East or West
                    if sortedIndices(1) == 2
                        %app.BlueEastLamp.Color = [0.529, 0.808, 0.922]; % Turn on East Lamp
                        app.WhiteEastLamp.Color='white';
                    else
                        %app.BlueWestLamp.Color = [0.529, 0.808, 0.922]; % Turn on West Lamp
                        app.WhiteWestLamp.Color='white';
                    end
            
                    if sortedIndices(2) == 1 || sortedIndices(2) == 3 % North or South
                        if sortedIndices(2) == 1
                            app.WhiteNorthLamp.Color = 'white';  % Turn on North Lamp
                        else
                            app.WhiteSouthLamp.Color = 'white'; % Turn on South Lamp
                        end
                    end
                end
            end

        end

        function updateStatistics(app)
            if app.MinCheckbox.Value
                disp(['Minimum Lux: ', num2str(app.LuxMin)]);
            end
            if app.MaxCheckbox.Value
                disp(['Maximum Lux: ', num2str(app.LuxMax)]);
            end
            if app.MeanCheckbox.Value
                disp(['Mean Lux: ', num2str(app.LuxMean)]);
            end
        end
        
        function app = ZoomApp
            % Create UIFigure and components (FourinOne, Sliders)
            createComponents(app);
        end
        
        
        function updatePlot(app)
            try
                % Read data from the serial port
                data = readline(app.s);
                tokens = strsplit(data, ' | ');
        
                if length(tokens) == 5
                    north = str2double(tokens{1}(7:end));
                    east = str2double(tokens{2}(6:end));
                    south = str2double(tokens{3}(7:end));
                    west = str2double(tokens{4}(6:end));
                    lightRef = str2double(strtrim(tokens{5}(11:end)));
        
                    % Store the data in the arrays
                    if ~isnan(north) && ~isnan(east) && ~isnan(south) && ~isnan(west) && ~isnan(lightRef)
                        app.northData(end+1) = north;
                        app.eastData(end+1) = east;
                        app.southData(end+1) = south;
                        app.westData(end+1) = west;
                        app.luxRefData(end+1) = lightRef;
        
                        % Plot all LDR data on a single graph
                        axes(app.ax_LDRPlot);  % Use the axes component
                        cla;  % Clear the plot
                        hold on;
        
                        plot(app.northData, 'g', 'DisplayName', 'North LDR');
                        plot(app.eastData, 'b', 'DisplayName', 'East LDR');
                        plot(app.southData, 'r', 'DisplayName', 'South LDR');
                        plot(app.westData, 'm', 'DisplayName', 'West LDR');
                        
                        % Optionally, plot the light reference data
                        % plot(app.luxRefData, 'k', 'DisplayName', 'LUX REF');
                        
                        xlabel('Time (s)');
                        ylabel('LDR Intensity');
                        title('LDR Sensor Intensities Over Time');
                        legend('show');
                        hold off;  % Release the plot hold
                    else
                        disp('Invalid data received');
                    end
                else
                    disp('Invalid data format');
                end
            catch ME
                disp('Error reading data');
                disp(ME.message);
            end
        end


    end
    
    methods (Access = private)
        
        function togglePlotMode(app)
            app.polar=true;
            % Clear existing plots
            cla(app.LuxRef);
            
            % Determine the current position for plotting
            currentPos = min([length(app.northData), length(app.eastData), ...
                              length(app.southData), length(app.westData)]);
            
            if currentPos == 0
                warning('No data available for plotting.');
                return;
            end
            
            % Get the current values for each direction
            northValue = app.northData(currentPos);
            eastValue = app.eastData(currentPos);
            southValue = app.southData(currentPos);
            westValue = app.westData(currentPos);
            
            % Angles for polar plot
            theta = [0, pi/2, pi, 3*pi/2]; % 0° (North), 90° (East), 180° (South), 270° (West)
            values = [northValue, eastValue, southValue, westValue];
            
            if app.PolarCheckbox.Value % If checkbox is checked, use polar coordinates
                % Plot a single point for each direction
                polar(app.LuxRef, theta(1), northValue, 'ro'); % North in red
                hold(app.LuxRef, 'on');
                polar(app.LuxRef, theta(2), eastValue, 'go'); % East in green
                polar(app.LuxRef, theta(3), southValue, 'bo'); % South in blue
                polar(app.LuxRef, theta(4), westValue, 'mo'); % West in magenta
                hold(app.LuxRef, 'off');
        
                % Add a title
                title(app.LuxRef, 'Polar Plot of LDR Intensities');
            else
                % Cartesian plot: Display each direction as a single point
                scatter(app.LuxRef, currentPos, northValue, 'ro', 'DisplayName', 'North');
                hold(app.LuxRef, 'on');
                scatter(app.LuxRef, currentPos, eastValue, 'go', 'DisplayName', 'East');
                scatter(app.LuxRef, currentPos, southValue, 'bo', 'DisplayName', 'South');
                scatter(app.LuxRef, currentPos, westValue, 'mo', 'DisplayName', 'West');
                hold(app.LuxRef, 'off');
                
                % Configure Cartesian axes
                %xlabel(app.PolarLDR, 'Time (s)');
                %ylabel(app.PolarLDR, 'LDR Intensity');
                %legend(app.PolarLDR, 'show');
                %title(app.PolarLDR, 'Cartesian Plot of LDR Intensities');
            end
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LDRsPanel.Enable = 'on';  
            app.AnalysisPanel.Enable = 'on';  
            app.DataPanel.Enable = 'on';  

            % Initialize checkbox values (unchecked by default)
            app.MinCheckbox.Value = false;
            app.MaxCheckbox.Value = false;
            app.MeanCheckbox.Value = false;
            app.polar=false;

            % Add callback functions for the checkboxes
            app.MinCheckbox.ValueChangedFcn = @(src, event) MinCheckboxValueChanged(app, event);
            app.MaxCheckbox.ValueChangedFcn = @(src, event) MaxCheckboxValueChanged(app, event);
            app.MeanCheckbox.ValueChangedFcn = @(src, event) MeanCheckboxValueChanged(app, event);

            % Initialize arrays for storing data
            app.northData = [];
            app.eastData = [];
            app.southData = [];
            app.westData = [];
            app.luxRefData = [];
            app.PlotTimer;
        end

        % Size changed function: LogInTab
        function LogInTabSizeChanged(app, event)

        end

        % Button pushed function: LogInButton
        function LogInButtonPushed(app, event)
            enteredUsername = app.UsernameField.Value; % Replace UsernameField with your field's name
            enteredPassword = app.PasswordField.Value; % Replace PasswordField with your field's name
    
            % Validate credentials
            if strcmp(enteredUsername, app.ValidUsername) && strcmp(enteredPassword, app.ValidPassword)
                % Success: Display message and enable Tab 2
                app.StatusLabel.Text = 'Login successful!';
                app.StatusLabel.FontColor = [0, 0.5, 0]; % Green text
                app.LDRsPanel.Enable = 'on'; % Enable Tab 2
                app.AnalysisPanel.Enable = 'on';
                app.DataPanel.Enable = 'on'; 
            else
                % Failure: Display error message
                app.StatusLabel.Text = 'Invalid credentials. Try again.';
                app.StatusLabel.FontColor = [1, 0, 0]; % Red text
            end
        end

        % Button pushed function: UpdateCredential
        function UpdateCredentialButtonPushed(app, event)
            newUsername = app.UsernameField.Value; % Get new username
            newPassword = app.PasswordField.Value; % Get new password
            
            if isempty(newUsername) || isempty(newPassword)
                app.StatusLabel.Text = 'Username and password cannot be empty.';
                app.StatusLabel.FontColor = [1, 0, 0]; % Red text
            else
                app.ValidUsername = newUsername; % Update the username
                app.ValidPassword = newPassword; % Update the password
                app.StatusLabel.Text = 'Credentials updated successfully!';
                app.StatusLabel.FontColor = [0, 0.5, 0]; % Green text
            end

        end

        % Callback function
        function StartButtonNorthPushed(app, event)
             % Initialize serial communicatio        

            % Initialize data arrays
            luxData = [];
            voltageData = [];
            theta = []; % Initialize angle for polar plot (in radians)

            % Ensure Axes are cleared
            cla(app.NorthLDR);
            cla(app.PolarLDR);

            try
                % Infinite loop to read and plot data
                while true
                    % Read and parse data
                    data = readline(s); % Read from Arduino
                    tokens = strsplit(data, ' |');
                    
                    if length(tokens) == 2
                        % Extract lux and voltage values
                        lux = str2double(tokens{1}(6:end)); % Lux value
                        voltage = str2double(tokens{2}(8:end)); % Voltage value
                        luxData(end+1) = lux; % Append to Lux data
                        voltageData(end+1) = voltage; % Append to Voltage data

                        % Update theta for polar plot
                        theta = linspace(0, 2*pi, length(luxData));

                        % Cartesian plot for Lux on NorthLDR
                        plot(app.NorthLDR, luxData, 'b');
                        xlabel(app.NorthLDR, 'Time (s)');
                        ylabel(app.NorthLDR, 'Lux');
                        title(app.NorthLDR, 'Light Intensity (Lux) Over Time');

                        % Enable Voltage Cartesian plot only if CheckboxVoltage is checked
                        if app.PolarCheckbox.Value
                            plot(app.NorthLDR, voltageData, 'r');
                            xlabel(app.NorthLDR, 'Time (s)');
                            ylabel(app.NorthLDR, 'Voltage (V)');
                            title(app.NorthLDR, 'Calculated Voltage Over Time');   
                        end

                        % Polar plot for Lux on SouthLDR
                        axes(app.PolarLDR); % Activate SouthLDR
                        cla(app.PolarLDR); % Clear existing content
                        polar(theta, luxData, 'b');
                        title(app.PolarLDR, 'Polar Plot of Lux Over Time');

                        % Pause for a while to allow for real-time updates
                        drawnow;
                        pause(1);
                    else
                        warning('Invalid or incomplete data received.');
                    end
                end
            catch ME
                % Close serial communication in case of an error
                clear s;
                rethrow(ME);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Initialize serial object
             if isempty(app.serialObj)
                app.serialObj = serialport('COM5', 115200); % Reinitialize serial object
            end
            app.EnablePlotUpdates = true;
            try
                while true
                    % Read data from the serial port
                    data = readline(app.serialObj);
                    
                    % Debug: Display raw data
                    disp(data); 
        
                    % Validate data type
                    if ~ischar(data) && ~isstring(data)
                        warning('Received data is not a string or character vector.');
                        continue; % Skip processing this iteration
                    end
        
                    % Split the received data
                    tokens = strsplit(char(data), ' | '); % Convert to char if needed
        
                    % Check the number of tokens
                    if length(tokens) == 5
                        % Extract and parse data
                        north = str2double(tokens{1}(7:end));
                        east = str2double(tokens{2}(6:end));
                        south = str2double(tokens{3}(7:end));
                        west = str2double(tokens{4}(6:end));
                        lightRef = str2double(strtrim(tokens{5}(11:end)));
        
                        % Validate parsed data
                        if ~isnan(north) && ~isnan(east) && ~isnan(south) && ~isnan(west) && ~isnan(lightRef)
                            % Update data arrays
                            app.northData(end+1) = north;
                            app.eastData(end+1) = east;
                            app.southData(end+1) = south;
                            app.westData(end+1) = west;
                            app.luxRefData(end+1) = lightRef;
        
                            % Update the plots
                            updatePlots(app);
                            drawnow;
                        else
                            warning('Invalid numeric data received, skipping.');
                        end
                    else
                        warning('Received incomplete or invalid data, skipping.');
                    end
                end
            catch ME
                % Cleanup on error
                clear app.serialObj;
                rethrow(ME);
            end


        end

        % Value changed function: PolarCheckbox
        function PolarCheckboxValueChanged(app, event)
            if app.PolarCheckbox.Value % Access the Value property correctly
                app.polar = true; % Assuming 'polar' is a property of the app
                togglePlotMode(app);
            else
                app.polar = false;
                cla(app.LuxRef);
            end
        end

        % Clicked callback: PlotDropdown
        function PlotDropdownClicked(app, event)
            
        end

        % Value changed function: PlotDropdown
        function PlotDropdownValueChanged(app, event)
            % Get the selected plot from the dropdown
            selectedPlot = app.PlotDropdown.Value;
            dataToSave = []; % Initialize the data array
        
            % Determine which data to save based on selection
            switch selectedPlot
                case 'North LDR'
                    dataToSave = app.northData;
                case 'East LDR'
                    dataToSave = app.eastData;
                case 'South LDR'
                    dataToSave = app.southData;
                case 'West LDR'
                    dataToSave = app.westData;
                case 'Lux Reference'
                    dataToSave = app.luxRefData;
                otherwise
                    uialert(app.UIFigure, 'Invalid selection. Please choose a valid plot.', 'Error');
                    return;
            end
        
            % Ensure there is data to save
            if isempty(dataToSave)
                uialert(app.UIFigure, 'No data available to save.', 'Save Error');
                return;
            end
        
            % Create time vector based on the length of the data
            timeVector = (1:length(dataToSave))';  % Assuming time is in increments of 1 (can be modified)
        
            % Combine data into a table for saving
            dataTable = table(timeVector, dataToSave', 'VariableNames', {'Time', 'Lux'});
        
            % Open a file dialog to choose the save location
            [file, path] = uiputfile('*.csv', 'Save Plot Data As');
            if isequal(file, 0) || isequal(path, 0)
                % User canceled the dialog
                uialert(app.UIFigure, 'File save operation canceled.', 'Canceled');
                return;
            end
        
            % Construct the full file path
            fullFilePath = fullfile(path, file);
        
            % Save the data to the selected location
            try
                writetable(dataTable, fullFilePath); % Save as CSV with time and lux columns
                msg = sprintf('Data successfully saved to %s', fullFilePath);
                uialert(app.UIFigure, msg, 'Success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
             
        end

        % Button pushed function: LoadPlotButton
        function LoadPlotButtonPushed(app, event)
         % Prompt user to select a file
            [file, path] = uigetfile('*.csv', 'Select a Data File');
            if isequal(file, 0)
                disp('User canceled load operation.');
                return;
            end
        
            % Load the selected file
            filePath = fullfile(path, file);
            try
                data = readtable(filePath);
                if ~all(ismember({'Time', 'Lux'}, data.Properties.VariableNames))
                    uialert(app.UIFigure, 'The selected file does not contain the required variables.', 'Load Error');
                    return;
                end
        
                % Plot the data on AnalysisPlot
                plot(app.AnalysisPlot, data.Time, data.Lux, 'g');
                xlabel(app.AnalysisPlot, 'Time (s)');
                ylabel(app.AnalysisPlot, 'Lux');
                title(app.AnalysisPlot, 'Loaded Data from File');
                hold(app.AnalysisPlot, 'on');  % Keep the current plot
        
                % Calculate min, max, and mean directly from the Lux data
                LuxMin = min(data.Lux);
                LuxMax = max(data.Lux);
                LuxMean = mean(data.Lux);
        
                % Find the time points corresponding to min, max, and mean
                MinTime = data.Time(data.Lux == LuxMin);
                MaxTime = data.Time(data.Lux == LuxMax);
                MeanTime = data.Time(abs(data.Lux - LuxMean) == min(abs(data.Lux - LuxMean)));  % Find closest time to mean
        
                % Update the text of the labels to display the calculated values
                app.Label.Text = [num2str(LuxMin)];
                app.Label_2.Text = [num2str(LuxMax)];
                app.Label_3.Text = [num2str(LuxMean)];
        
                % Optionally, update the checkbox states (if needed)
                app.MinCheckbox.Value = true;  % Default to displaying min
                app.MaxCheckbox.Value = true;  % Default to displaying max
                app.MeanCheckbox.Value = true; % Default to displaying mean
        
                % Display statistics based on checkbox selections
                if app.MinCheckbox.Value
                    plot(app.AnalysisPlot, MinTime, LuxMin, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 8);  % Red point for min
                end
                if app.MaxCheckbox.Value
                    plot(app.AnalysisPlot, MaxTime, LuxMax, 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 8);  % Blue point for max
                end
                if app.MeanCheckbox.Value
                    plot(app.AnalysisPlot, MeanTime, LuxMean, 'ko', 'MarkerFaceColor', 'k', 'MarkerSize', 8);  % Black point for mean
                end
        
            catch ME
                uialert(app.UIFigure, 'Failed to load or plot data. Please check the file format.', 'Load Error');
                disp(ME.message);
            end    
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            
        end

        % Value changed function: MinCheckbox
        function MinCheckboxValueChanged(app, event)
            if app.MinCheckbox.Value
                app.MinCheckbox.Value = true; 
                % Re-plot the min point
                LuxMin = str2double(app.Label.Text);
                MinTime = app.Data.Time(app.Data.Lux == LuxMin);
                plot(app.AnalysisPlot, MinTime, LuxMin, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 8);
            else
                % Remove the min point from the plot
                hold(app.AnalysisPlot, 'off');
                plot(app.AnalysisPlot, app.Data.Time, app.Data.Lux, 'g');  % Redraw the original plot
                hold(app.AnalysisPlot, 'on');
                if app.MaxCheckbox.Value
                    plot(app.AnalysisPlot, app.MaxTime, app.LuxMax, 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 8);
                end
                if app.MeanCheckbox.Value
                    plot(app.AnalysisPlot, app.MeanTime, app.LuxMean, 'ko', 'MarkerFaceColor', 'k', 'MarkerSize', 8);
                end
            end          
        end

        % Value changed function: MaxCheckbox
        function MaxCheckboxValueChanged(app, event)
            % Check if Max checkbox is selected
            if app.MaxCheckbox.Value
                % Re-plot the max point
                LuxMax = str2double(app.Label_2.Text);
                MaxTime = app.Data.Time(app.Data.Lux == LuxMax);
                plot(app.AnalysisPlot, MaxTime, LuxMax, 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 8);
            else
                % Remove the max point from the plot
                hold(app.AnalysisPlot, 'off');
                plot(app.AnalysisPlot, app.Data.Time, app.Data.Lux, 'g');  % Redraw the original plot
                hold(app.AnalysisPlot, 'on');
                if app.MinCheckbox.Value
                    plot(app.AnalysisPlot, app.MinTime, app.LuxMin, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 8);
                end
                if app.MeanCheckbox.Value
                    plot(app.AnalysisPlot, app.MeanTime, app.LuxMean, 'ko', 'MarkerFaceColor', 'k', 'MarkerSize', 8);
                end
            end
        end

        % Value changed function: MeanCheckbox
        function MeanCheckboxValueChanged(app, event)
               % Check if Mean checkbox is selected
            if app.MeanCheckbox.Value
                % Re-plot the mean point
                LuxMean = str2double(app.Label_3.Text);
                MeanTime = app.Data.Time(abs(app.Data.Lux - LuxMean) == min(abs(app.Data.Lux - LuxMean)));
                plot(app.AnalysisPlot, MeanTime, LuxMean, 'ko', 'MarkerFaceColor', 'k', 'MarkerSize', 8);
            else
                % Remove the mean point from the plot
                hold(app.AnalysisPlot, 'off');
                plot(app.AnalysisPlot, app.Data.Time, app.Data.Lux, 'g');  % Redraw the original plot
                hold(app.AnalysisPlot, 'on');
                if app.MinCheckbox.Value
                    plot(app.AnalysisPlot, app.MinTime, app.LuxMin, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 8);
                end
                if app.MaxCheckbox.Value
                    plot(app.AnalysisPlot, app.MaxTime, app.LuxMax, 'bo', 'MarkerFaceColor', 'b', 'MarkerSize', 8);
                end
            end
        end

        % Value changed function: VerticalSlider
        function VerticalSliderValueChanged(app, event)
            yZoomFactor = app.VerticalSlider.Value;  % Get the current value of the vertical slider
            
            % Adjust the Y-axis limits based on the zoom factor
            yLimits = app.AnalysisPlot.YLim;
            yCenter = mean(yLimits);  % Get the current center of the Y-axis
            
            % Zoom by scaling the height around the center
            newYHeight = (yLimits(2) - yLimits(1)) * yZoomFactor;
            newYLim = [yCenter - newYHeight / 2, yCenter + newYHeight / 2];
            
            % Ensure that the second element is greater than the first
            if newYLim(2) > newYLim(1)
                app.AnalysisPlot.YLim = newYLim;  % Set the new Y-axis limits
            else
                % If zoom causes invalid range, reset to original limits
                app.AnalysisPlot.YLim = yLimits;
            end
        end

        % Value changed function: HorizontalSlider
        function HorizontalSliderValueChanged(app, event)
             xZoomFactor = app.HorizontalSlider.Value;  % Get the current value of the horizontal slider
            
            % Adjust the X-axis limits based on the zoom factor
            xLimits = app.AnalysisPlot.XLim;
            xCenter = mean(xLimits);  % Get the current center of the X-axis
            
            % Zoom by scaling the width around the center
            newXWidth = (xLimits(2) - xLimits(1)) * xZoomFactor;
            newXLim = [xCenter - newXWidth / 2, xCenter + newXWidth / 2];
            
            % Ensure that the second element is greater than the first
            if newXLim(2) > newXLim(1)
                app.AnalysisPlot.XLim = newXLim;  % Set the new X-axis limits
            else
                % If zoom causes invalid range, reset to original limits
                app.AnalysisPlot.XLim = xLimits;
            end
        end

        % Callback function
        function btn_StartStopButtonPushed(app, event)

        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            % Initialize serial object
            if isempty(app.serialObj)
                app.serialObj = serialport('COM5', 115200); % Reinitialize serial object
            end
            app.EnablePlotUpdate2 = true;
            try
                while true
                    % Read data from the serial port
                    data = readline(app.serialObj);
                    
                    % Debug: Display raw data
                    disp(data); 
        
                    % Validate data type
                    if ~ischar(data) && ~isstring(data)
                        warning('Received data is not a string or character vector.');
                        continue; % Skip processing this iteration
                    end
        
                    % Split the received data
                    tokens = strsplit(char(data), ' | '); % Convert to char if needed
        
                    % Check the number of tokens
                    if length(tokens) == 5
                        % Extract and parse data
                        north2 = str2double(tokens{1}(7:end));
                        east2 = str2double(tokens{2}(6:end));
                        south2 = str2double(tokens{3}(7:end));
                        west2 = str2double(tokens{4}(6:end));
        
                        % Validate parsed data
                        if ~isnan(north2) && ~isnan(east2) && ~isnan(south2) && ~isnan(west2) 
                            % Update data arrays
                            app.northData(end+1) = north2;
                            app.eastData(end+1) = east2;
                            app.southData(end+1) = south2;
                            app.westData(end+1) = west2;
        
                            % Update the plots
                            updatePlots2(app);
                            drawnow;
                        else
                            warning('Invalid numeric data received, skipping.');
                        end
                    else
                        warning('Received incomplete or invalid data, skipping.');
                    end
                end
            catch ME
                % Cleanup on error
                clear app.serialObj;
                rethrow(ME);
            end
        end

        % Callback function
        function StartButton_3Pushed(app, event)
          
        end

        % Button pushed function: SerialConnectionButton
        function SerialConnectionButtonPushed(app, event)
            if isempty(app.serialObj)
            app.serialObj = serialport('COM5', 115200); % Reinitialize serial object
            end 
            try
                
                % Initialize serial communication with Arduino
                app.serialObj = serialport('COM5', 115200); 
                % Display success message in the label
                app.StatusLabel2.Text = 'Connection Successful';
                app.StatusLabel2.FontColor = [0, 0.6, 0]; % Green color
                return;
            catch ME
                %app.StatusLabel2.FontColor = [0, 0.6, 0]; % Green color
                %app.StatusLabel2.Text = 'Connection Failed';
                %app.StatusLabel2.FontColor = [1, 0, 0]; % Red color
                return;
            end
        end

        % Button pushed function: StopLDR
        function StopLDRButtonPushed(app, event)
            app.StopFlag = true; 
            app.EnablePlotUpdates = false;
            if ~isempty(app.serialObj) % Ensure the serial object exists
                clear app.serialObj; 
            end
        end

        % Button pushed function: StopTab3
        function StopTab3Pushed(app, event)
            app.StopFlag = true; 
            app.EnablePlotUpdate2 = false;
            if ~isempty(app.serialObj) % Ensure the serial object exists
                clear app.serialObj; 
            end
        end

        % Callback function: not associated with a component
        function ButtonPushed(app, event)
            if isempty(app.serialObj)
                app.serialObj = serialport('COM5', 115200); % Reinitialize serial object
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LogInTab = uitab(app.TabGroup);
            app.LogInTab.SizeChangedFcn = createCallbackFcn(app, @LogInTabSizeChanged, true);
            app.LogInTab.Title = 'Log In';
            app.AUTENTICATIONLabel = uilabel(app.LogInTab);
            app.AUTENTICATIONLabel.Text = 'AUTENTICATION';
            app.LogInButton = uibutton(app.LogInTab, 'push');
            app.LogInButton.ButtonPushedFcn = createCallbackFcn(app, @LogInButtonPushed, true);
            app.LogInButton.Text = 'LOG IN ';
            app.StatusLabel = uilabel(app.LogInTab);
            app.StatusLabel.Text = '...';
            app.UsernameField = uieditfield(app.LogInTab, 'text');
            app.PasswordField = uieditfield(app.LogInTab, 'text');
            app.USERNAMELabel = uilabel(app.LogInTab);
            app.USERNAMELabel.Text = 'USERNAME:';
            app.PASSWORDLabel = uilabel(app.LogInTab);
            app.PASSWORDLabel.Text = 'PASSWORD:';
            app.UpdateCredential = uibutton(app.LogInTab, 'push');
            app.UpdateCredential.ButtonPushedFcn = createCallbackFcn(app, @UpdateCredentialButtonPushed, true);
            app.UpdateCredential.Text = 'SIGN UP';
            app.LDRsTab = uitab(app.TabGroup);
            app.LDRsTab.Title = 'LDRs';
            app.LDRsTab.Tag = 'GraphTab';
            app.LDRsPanel = uipanel(app.LDRsTab);
            app.LDRsPanel.Enable = 'off';
            app.LDRsPanel.Title = 'Data';
            app.NorthLDR = uiaxes(app.LDRsPanel);
            title(app.NorthLDR, 'North LDR')
            xlabel(app.NorthLDR, 'X')
            ylabel(app.NorthLDR, 'Y')
            zlabel(app.NorthLDR, 'Z')
            app.PolarLDR = uiaxes(app.LDRsPanel);
            title(app.PolarLDR, 'Title')
            xlabel(app.PolarLDR, 'X')
            ylabel(app.PolarLDR, 'Y')
            zlabel(app.PolarLDR, 'Z')
            app.WestLDR = uiaxes(app.LDRsPanel);
            title(app.WestLDR, 'Title')
            xlabel(app.WestLDR, 'X')
            ylabel(app.WestLDR, 'Y')
            zlabel(app.WestLDR, 'Z')
            app.EastLDR = uiaxes(app.LDRsPanel);
            title(app.EastLDR, 'North LDR')
            xlabel(app.EastLDR, 'X')
            ylabel(app.EastLDR, 'Y')
            zlabel(app.EastLDR, 'Z')
            app.SouthLDR = uiaxes(app.LDRsPanel);
            title(app.SouthLDR, 'North LDR')
            xlabel(app.SouthLDR, 'X')
            ylabel(app.SouthLDR, 'Y')
            zlabel(app.SouthLDR, 'Z')
            app.LuxRef = uiaxes(app.LDRsPanel);
            title(app.LuxRef, 'Title')
            xlabel(app.LuxRef, 'X')
            ylabel(app.LuxRef, 'Y')
            zlabel(app.LuxRef, 'Z')
            app.ADQUISITIONLabel = uilabel(app.LDRsPanel);
            app.ADQUISITIONLabel.Text = 'ADQUISITION';
            app.SerialConnectionButton = uibutton(app.LDRsPanel, 'push');
            app.SerialConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @SerialConnectionButtonPushed, true);
            app.SerialConnectionButton.Text = 'Serial Connection';
            app.PolarCheckbox = uicheckbox(app.LDRsPanel);
            app.PolarCheckbox.ValueChangedFcn = createCallbackFcn(app, @PolarCheckboxValueChanged, true);
            app.PolarCheckbox.Text = 'Polar';
            app.StartButton = uibutton(app.LDRsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Plot';
            app.SAVELabel = uilabel(app.LDRsPanel);
            app.SAVELabel.Text = 'SAVE: ';
            app.PlotDropdown = uidropdown(app.LDRsPanel);
            app.PlotDropdown.Items = {'North LDR', 'East LDR', 'West LDR', 'South LDR', 'Lux Reference'};
            app.PlotDropdown.ValueChangedFcn = createCallbackFcn(app, @PlotDropdownValueChanged, true);
            app.PlotDropdown.ClickedFcn = createCallbackFcn(app, @PlotDropdownClicked, true);
            app.PlotDropdown.Value = 'East LDR';
            app.StatusLabel2 = uilabel(app.LDRsPanel);
            app.StatusLabel2.Text = '...';
            app.StopLDR = uibutton(app.LDRsPanel, 'push');
            app.StopLDR.ButtonPushedFcn = createCallbackFcn(app, @StopLDRButtonPushed, true);
            app.StopLDR.Text = 'Stop';
            app.GraphTab_2 = uitab(app.TabGroup);
            app.GraphTab_2.Title = 'Graph';
            app.NorthLDR_2 = uiaxes(app.GraphTab_2);
            title(app.NorthLDR_2, 'North LDR')
            xlabel(app.NorthLDR_2, 'X')
            ylabel(app.NorthLDR_2, 'Y')
            zlabel(app.NorthLDR_2, 'Z')
            app.PolarLDR_2 = uiaxes(app.GraphTab_2);
            title(app.PolarLDR_2, 'Title')
            xlabel(app.PolarLDR_2, 'X')
            ylabel(app.PolarLDR_2, 'Y')
            zlabel(app.PolarLDR_2, 'Z')
            app.WestLDR_2 = uiaxes(app.GraphTab_2);
            title(app.WestLDR_2, 'Title')
            xlabel(app.WestLDR_2, 'X')
            ylabel(app.WestLDR_2, 'Y')
            zlabel(app.WestLDR_2, 'Z')
            app.EastLDR_2 = uiaxes(app.GraphTab_2);
            title(app.EastLDR_2, 'North LDR')
            xlabel(app.EastLDR_2, 'X')
            ylabel(app.EastLDR_2, 'Y')
            zlabel(app.EastLDR_2, 'Z')
            app.SouthLDR_2 = uiaxes(app.GraphTab_2);
            title(app.SouthLDR_2, 'North LDR')
            xlabel(app.SouthLDR_2, 'X')
            ylabel(app.SouthLDR_2, 'Y')
            zlabel(app.SouthLDR_2, 'Z')
            app.DataPanel = uipanel(app.GraphTab_2);
            app.DataPanel.Title = 'Data';
            app.West2 = uiaxes(app.DataPanel);
            title(app.West2, 'Title')
            xlabel(app.West2, 'X')
            ylabel(app.West2, 'Y')
            zlabel(app.West2, 'Z')
            app.North2 = uiaxes(app.DataPanel);
            title(app.North2, 'Title')
            xlabel(app.North2, 'X')
            ylabel(app.North2, 'Y')
            zlabel(app.North2, 'Z')
            app.East2 = uiaxes(app.DataPanel);
            title(app.East2, 'Title')
            xlabel(app.East2, 'X')
            ylabel(app.East2, 'Y')
            zlabel(app.East2, 'Z')
            app.South2 = uiaxes(app.DataPanel);
            title(app.South2, 'Title')
            xlabel(app.South2, 'X')
            ylabel(app.South2, 'Y')
            zlabel(app.South2, 'Z')
            app.WhiteSouthLamp = uilamp(app.DataPanel);
            app.WhiteWestLamp = uilamp(app.DataPanel);
            app.BlueWestLamp = uilamp(app.DataPanel);
            app.GreenCenterLamp = uilamp(app.DataPanel);
            app.BlueEastLamp = uilamp(app.DataPanel);
            app.WhiteEastLamp = uilamp(app.DataPanel);
            app.WhiteNorthLamp = uilamp(app.DataPanel);
            app.StartButton_2 = uibutton(app.DataPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Plot';
            app.SensorLabel = uilabel(app.DataPanel);
            app.SensorLabel.Text = 'Sensor';
            app.StopTab3 = uibutton(app.DataPanel, 'push');
            app.StopTab3.ButtonPushedFcn = createCallbackFcn(app, @StopTab3Pushed, true);
            app.StopTab3.Text = 'Stop';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Statistics';
            app.AnalysisPanel = uipanel(app.AnalysisTab);
            app.AnalysisPanel.Enable = 'off';
            app.AnalysisPanel.Title = 'Analysis';
            app.AnalysisPlot = uiaxes(app.AnalysisPanel);
            title(app.AnalysisPlot, 'Title')
            xlabel(app.AnalysisPlot, 'X')
            ylabel(app.AnalysisPlot, 'Y')
            zlabel(app.AnalysisPlot, 'Z')
            app.SliderLabel = uilabel(app.AnalysisPanel);
            app.SliderLabel.Text = 'Slider';
            app.HorizontalSlider = uislider(app.AnalysisPanel);
            app.HorizontalSlider.Limits = [0 10];
            app.HorizontalSlider.ValueChangedFcn = createCallbackFcn(app, @HorizontalSliderValueChanged, true);
            app.Slider_2Label = uilabel(app.AnalysisPanel);
            app.Slider_2Label.Text = 'Slider';
            app.VerticalSlider = uislider(app.AnalysisPanel);
            app.VerticalSlider.Limits = [0 10];
            app.VerticalSlider.ValueChangedFcn = createCallbackFcn(app, @VerticalSliderValueChanged, true);
            app.LoadPlotButton = uibutton(app.AnalysisPanel, 'push');
            app.LoadPlotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotButtonPushed, true);
            app.LoadPlotButton.Text = 'Load Plot';
            app.MinCheckbox = uicheckbox(app.AnalysisPanel);
            app.MinCheckbox.ValueChangedFcn = createCallbackFcn(app, @MinCheckboxValueChanged, true);
            app.MinCheckbox.Text = 'Min';
            app.MaxCheckbox = uicheckbox(app.AnalysisPanel);
            app.MaxCheckbox.ValueChangedFcn = createCallbackFcn(app, @MaxCheckboxValueChanged, true);
            app.MaxCheckbox.Text = 'Max';
            app.MeanCheckbox = uicheckbox(app.AnalysisPanel);
            app.MeanCheckbox.ValueChangedFcn = createCallbackFcn(app, @MeanCheckboxValueChanged, true);
            app.MeanCheckbox.Text = 'Mean';
            app.Label = uilabel(app.AnalysisPanel);
            app.Label.Text = '..';
            app.Label_2 = uilabel(app.AnalysisPanel);
            app.Label_2.Text = '...';
            app.Label_3 = uilabel(app.AnalysisPanel);
            app.Label_3.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proejct2Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end